classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        Button_SLM_connect              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Lamp_SLM_connected              matlab.ui.control.Lamp
        LensphaseSpinnerLabel           matlab.ui.control.Label
        Spinner_Lens_phase              matlab.ui.control.Spinner
        Button_send_lens_phase          matlab.ui.control.Button
        Lamp_sent_lens_phase            matlab.ui.control.Lamp
        Table_calibration_parameters    matlab.ui.control.Table
        Button_Generate_grid            matlab.ui.control.Button
        Check_include_lens_phase_Grid   matlab.ui.control.CheckBox
        Button_send_pattern_phase       matlab.ui.control.Button
        Button_calculate_pattern_phase  matlab.ui.control.Button
        Axes_Phase                      matlab.ui.control.UIAxes
        Axes_Target_intensity_pattern   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        verbose = 0;                        % Description
        SLM_attached = 0;                   % Description
        SLM                                 % Description
        lambda_um = 0.92;
        focalDist_um = 12.5e3;
        refractiveIndex = 1.33;
        objNA = 0.8;
        illuminationWavelength = 920e-9;
        SLM_size_px
        original_SLM_size_px
        blackBand
        lens_phase_spinner_value
        flatPhase_toBeSent
        nPoints = 3;
        step = 150;
        zoomXY = 0.51;
        rotation = 107;
        mirroring = 0;
        ITarget
        
        SLMControlGUI_parameters = struct(...
            ...% Meadowlark 512x512 is controlled through PCIe 16-bit
            ...'SLM_control_type','PCIe_16bit',...
            ...'SLM_LUTpath','C:\Program Files\Meadowlark Optics\Blink OverDrive Plus\LUT Files',... % should be a valid path, included in MATLAB path
            ...'SLM_globalLUTfilename','slm4073_at1064_P8.lut',...% should be a valid filename
            ...'SLM_SDKpath','C:\Program Files\Meadowlark Optics\Blink OverDrive Plus\SDK',...% should be a valid path, included in MATLAB path
            ...'SLM_size',[512 512],...
            ...'SLM_layout','square',...
            ...% Overdrive parameters (only for Meadowlark)
            ...'SLM_overdriveActive',0,...
            ...'SLM_regionalLUTfilename','slm4073_regional.txt',...% should be a valid filename
            ...'SLM_maxTransients',20,...% max 20
            ...
            ...% Hamamatsu X10468-07 is controlled through DVI
            'SLM_control_type','DVI',...
            'SLM_LUTpath','C:\Users\Public\Valentina\SLMControlGUI_v2.9_room1_20190131\SLMControlGUI\Hamamatsu',... % should be a valid path, included in MATLAB path
            'SLM_globalLUTfilename','lut.txt',...% should be a valid filename
            'SLM_size_px', [800,600]);            %[horizontal,vertical]
        
        IFT_field                    % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Check size of SLM. If rectangular, generate the blackbands to
            % be used at the borders of square calculations 
            app.original_SLM_size_px = app.SLMControlGUI_parameters.SLM_size_px;
            if size(app.original_SLM_size_px,1)~=size(app.original_SLM_size_px,2)
                app.blackBand = zeros(min(app.original_SLM_size_px), (max(app.original_SLM_size_px)-min(app.original_SLM_size_px))/2);
                app.SLM_size_px = [min(app.original_SLM_size_px) min(app.original_SLM_size_px)];
            end
            app.Axes_Phase.DataAspectRatio = [app.original_SLM_size_px(2) app.original_SLM_size_px(1) 1];
            
            % Initialization of the calibration table
            app.Table_calibration_parameters.Data = [app.nPoints, app.step, app.zoomXY, app.rotation, app.mirroring];
            app.Table_calibration_parameters.ColumnFormat = {'short','short','bank','short','short'};
            s1 = uistyle('FontColor','k','FontWeight','bold');
            addStyle(app.Table_calibration_parameters,s1,'column',[1 2]);
            
        end

        % Button pushed function: Button_SLM_connect
        function Button_SLM_connectPushed(app, event)
            
            if app.SLM_attached
                app.SLM = SLM_Hamamatsu(app.SLMControlGUI_parameters);
                app.Lamp_SLM_connected.Color = 'green';
            else
                app.Lamp_SLM_connected.Color = 'red';
            end
            
        end

        % Value changing function: Spinner_Lens_phase
        function Spinner_Lens_phaseValueChanging(app, event)
            
            app.lens_phase_spinner_value = event.Value;
            flatPhase = lensPhaseModulation(app.SLM_size_px, app.lens_phase_spinner_value, app.lambda_um, app.focalDist_um);
            flatPhase = [app.blackBand, flatPhase, app.blackBand];
            
            app.flatPhase_toBeSent = mod (flatPhase, 2*pi)./(2*pi);
            app.Lamp_sent_lens_phase.Color = 'black';
            
            % Show the image in the phase window
            pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
            imshow(app.flatPhase_toBeSent, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Phase.DataAspectRatioMode = 'manual';
            app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
        end

        % Button pushed function: Button_send_lens_phase
        function Button_send_lens_phasePushed(app, event)
            
            if app.SLM_attached
                app.SLM.sendMap(app.flatPhase_toBeSent);
                app.Lamp_sent_lens_phase.Color = 'green';
            else
                app.Lamp_sent_lens_phase.Color = 'red';
            end
            
        end

        % Button pushed function: Button_Generate_grid
        function Button_Generate_gridPushed(app, event)
            
            nPoints = app.Table_calibration_parameters.Data(1);
            step = app.Table_calibration_parameters.Data(2);
            zoomXY = app.Table_calibration_parameters.Data(3);
            rotation = app.Table_calibration_parameters.Data(4);
            mirroring = app.Table_calibration_parameters.Data(5);
            
            
            I = zeros(app.SLM_size_px);
            xp = [0:1:(nPoints-1)]*step;
            yp = xp;
            shift = (xp(end)-xp(1))/2;
            shiftX = app.SLM_size_px(1)/2;
            shiftY = app.SLM_size_px(2)/2;
            xp = xp-shift+shiftX;
            yp = yp-shift+shiftY;
            xy = [];
            for xx = 1:length(xp)
                for yy = 1:length(yp)
                    xy = [xy; xp(xx) yp(yy)];
                end
            end
            linearInd = sub2ind(size(I), xy(:,1), xy (:,2));
            I(linearInd) = 1;
            [~, Itransformed] = applyGeometricalTransformation3D(zoomXY,1,rotation,0,mirroring,I,1,1);
            
            targetPattern = Itransformed;
%             targetPattern = [app.blackBand, targetPattern, app.blackBand];
            app.ITarget = targetPattern;
            
            % Show the image in the Target Intensity window
            pbarOriginal = pbaspect(app.Axes_Target_intensity_pattern); % preserve the original PlotBoxAspectRatio
            imshow(Itransformed, [], 'Parent', app.Axes_Target_intensity_pattern, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Target_intensity_pattern.DataAspectRatioMode = 'manual';
            app.Axes_Target_intensity_pattern.PlotBoxAspectRatio = pbarOriginal;
            
        end

        % Button pushed function: Button_calculate_pattern_phase
        function Button_calculate_pattern_phasePushed(app, event)
            
            %Input beam amplitude
            sigma = 200;
            singleCircleImage = fspecial('gaussian', flip(app.SLM_size_px), sigma); 
            singleCircleImage=singleCircleImage/max(max(singleCircleImage)); % Normalize to 0-1. 
            initial_amplitude = singleCircleImage;
            
            %Input beam phase
            % initial_phase = (rand(size,size)*(2*pi))-pi;
            initial_phase = (rand(flip(app.SLM_size_px))*(2*pi))-pi;
            initial_field = initial_amplitude.*exp(1i*initial_phase);
            
            FT_field = fftshift(fft2(fftshift(initial_field)));
            
%             if app.verbose
%                 fontSize = 8;
%                 figure;
%                 subplot(2,3,2); 
%                 imshow(initial_amplitude, []); 
%                 title('input amplitude', 'FontSize', fontSize);
%                 axis off;
%                 subplot(2,3,3); 
%                 imshow(initial_phase, []); 
%                 title('SLM phase', 'FontSize', fontSize);
%                 axis off;
%                 subplot(2,3,5); 
%                 imshow(abs(FT_field).^2,[]);
%                 axis on;
%             end

            ee = 1;
            error = [];
            error1 = [];
            uniformity = [];
            efficiency = [];
            maschera = find(app.ITarget);
            % Iterations = 10;
            stopError = 1e-3;
            numIter = 0;
            h = waitbar(0,'Please wait...');
            maxNumIter = 200;
                
            % for ii = 1:Iterations
            while ee > stopError
                waitbar(numIter / (maxNumIter-1))
            % fprintf('Iteration: %d\n', ii) 
            
            FT_phase = angle(FT_field);
            FT_amplitude = abs(FT_field);
            Iachieved = FT_amplitude.^2;
            FT_field_updated = app.ITarget.*exp(1i*FT_phase);
            
            app.IFT_field = fftshift(ifft2(fftshift(FT_field_updated)));


            IFT_phase = angle(app.IFT_field);
            IFT_amplitude = abs(app.IFT_field);
            IFT_field_updated = initial_amplitude.*exp(1i*IFT_phase);
            
            FT_field = fftshift(fft2(fftshift(IFT_field_updated)));


            ee = sqrt( immse( Iachieved(:)./max(Iachieved(:)), app.ITarget(:)./max(app.ITarget(:)) ) );
            % Python implementation: errors' standard deviation
            ee1 = std(Iachieved(:)./max(Iachieved(:))-app.ITarget(:)./max(app.ITarget(:)));
            error = [error; ee];
            error1 = [error1; ee1];
            uniformity = [uniformity; 1-((max(Iachieved(maschera))-min(Iachieved(maschera)))/(max(Iachieved(maschera))+min(Iachieved(maschera))))];
            efficiency = [efficiency; sum(Iachieved(maschera))/sum(Iachieved(:))];
            
            numIter = numIter+1;
            if numIter > maxNumIter-1
                    break;
            end
            
%             if app.verbose
%                 subplot(2,3,5); 
%                 axis off;
%                 imshow(abs(FT_field).^2, []);
%                 title('reconstructed amplitude', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,3); 
%                 axis off;
%                 imshow(wrapTo2Pi(angle(IFT_field)), []);
%                 title('phase to be used', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,6); 
%                 axis off;
%                 imshow(wrapTo2Pi(angle(FT_field)), []);
%                 title('reconstructed phase', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,1); 
%                 imagesc(mat2rgbCplx(initial_amplitude.*exp(1i*wrapTo2Pi(angle(IFT_field))),max(initial_amplitude),1));
%                 pbaspect([max(SLM_size_px) min(SLM_size_px) 1]);
%                 axis off;
%                 title('field after SLM', 'FontSize', fontSize);
%                 drawnow;
%             
%                 subplot(2,3,4); 
%                 imagesc(mat2rgbCplx((abs(FT_field).^2).*exp(1i*wrapTo2Pi(angle(FT_field))),max((abs(FT_field).^2)),1));
%                 pbaspect([max(SLM_size_px) min(SLM_size_px) 1]);
%                 title('field at the sample', 'FontSize', fontSize);
%                 axis off;
%                 drawnow;
%             end
            
            
            end
            close(h)

            disp('Phase calculated')
        end

        % Button pushed function: Button_send_pattern_phase
        function Button_send_pattern_phasePushed(app, event)
            phase = [app.blackBand, angle(app.IFT_field), app.blackBand];
            if app.Check_include_lens_phase_Grid.Value
                phase = mod (phase+app.flatPhase_toBeSent, 2*pi)./(2*pi);
            end
            
            if app.SLM_attached
                app.SLM.sendMap(phase);
            end
            
            % Show the image in the Target Intensity window
            pbarOriginal = pbaspect(app.Axes_Phase); % preserve the original PlotBoxAspectRatio
            imshow(phase, [], 'Parent', app.Axes_Phase, 'Border', 'tight');
            % Revert the properties back to their original values
            app.Axes_Phase.PlotBoxAspectRatioMode = 'auto';
            app.Axes_Phase.DataAspectRatioMode = 'manual';
            app.Axes_Phase.PlotBoxAspectRatio = pbarOriginal;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'MATLAB App';
            app.Button_SLM_connect = uibutton(app.MainWindow, 'push');
            app.Button_SLM_connect.ButtonPushedFcn = createCallbackFcn(app, @Button_SLM_connectPushed, true);
            app.Button_SLM_connect.Text = 'Connect to SLM';
            app.Label = uilabel(app.MainWindow);
            app.Label.Text = '';
            app.Lamp_SLM_connected = uilamp(app.MainWindow);
            app.LensphaseSpinnerLabel = uilabel(app.MainWindow);
            app.LensphaseSpinnerLabel.Text = 'Lens phase';
            app.Spinner_Lens_phase = uispinner(app.MainWindow);
            app.Spinner_Lens_phase.Step = 0.01;
            app.Spinner_Lens_phase.ValueChangingFcn = createCallbackFcn(app, @Spinner_Lens_phaseValueChanging, true);
            app.Spinner_Lens_phase.ValueDisplayFormat = '%.2f';
            app.Spinner_Lens_phase.Value = 6.3;
            app.Button_send_lens_phase = uibutton(app.MainWindow, 'push');
            app.Button_send_lens_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_send_lens_phasePushed, true);
            app.Button_send_lens_phase.Text = 'Send Lens phase';
            app.Lamp_sent_lens_phase = uilamp(app.MainWindow);
            app.Table_calibration_parameters = uitable(app.MainWindow);
            app.Table_calibration_parameters.ColumnName = {'Points per side'; 'Points distance'; 'Zoom XY'; 'Rotation angle'; 'Mirroring'};
            app.Table_calibration_parameters.RowName = {};
            app.Table_calibration_parameters.ColumnEditable = true;
            app.Button_Generate_grid = uibutton(app.MainWindow, 'push');
            app.Button_Generate_grid.ButtonPushedFcn = createCallbackFcn(app, @Button_Generate_gridPushed, true);
            app.Button_Generate_grid.Text = {'Generate Grid '; ''};
            app.Check_include_lens_phase_Grid = uicheckbox(app.MainWindow);
            app.Check_include_lens_phase_Grid.Text = 'Include lens phase';
            app.Button_send_pattern_phase = uibutton(app.MainWindow, 'push');
            app.Button_send_pattern_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_send_pattern_phasePushed, true);
            app.Button_send_pattern_phase.Text = 'Send Pattern phase';
            app.Button_calculate_pattern_phase = uibutton(app.MainWindow, 'push');
            app.Button_calculate_pattern_phase.ButtonPushedFcn = createCallbackFcn(app, @Button_calculate_pattern_phasePushed, true);
            app.Button_calculate_pattern_phase.Text = 'Calculate pattern phase';
            app.Axes_Phase = uiaxes(app.MainWindow);
            title(app.Axes_Phase, 'Phase')
            app.Axes_Phase.XTick = [];
            app.Axes_Phase.YTick = [];
            app.Axes_Target_intensity_pattern = uiaxes(app.MainWindow);
            title(app.Axes_Target_intensity_pattern, 'Target Intensity Pattern')
            app.Axes_Target_intensity_pattern.XTick = [];
            app.Axes_Target_intensity_pattern.YTick = [];
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end